classdef least_Squares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Least_Square     matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        Solve            matlab.ui.control.Button
        xEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        A                matlab.ui.control.Label
        A_VaL            matlab.ui.control.Label
        B                matlab.ui.control.Label
        B_Val            matlab.ui.control.Label
        error            matlab.ui.control.Label
        error_val        matlab.ui.control.Label
        Mean             matlab.ui.control.Label
        Mean_val         matlab.ui.control.Label
        EnterTheDataseparatedwithacommaLabel  matlab.ui.control.Label
        RightPanel       matlab.ui.container.Panel
        plot             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Least_Square.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {320, 320};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {373, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Solve
        function SolvePushed(app, event)
            clear vars;clc
            app.plot.cla;
            X=app.xEditField.Value;
            X=str2double(strsplit(X,','));
            Y=app.yEditField.Value;
            Y=str2double(strsplit(Y,','));
            %% Least Square
            X_squar=X.^2;
            X_Y=X.*Y;
            %% Solve For A & B
            syms A B
            Sol=vpasolve([sum(X_squar)*A+sum(X)*B==sum(X_Y),sum(X)*A+length(X)*B==sum(Y)],[A,B]);
            A=Sol.A;
            B=Sol.B;
            app.A_VaL.Text= string(A);
            app.B_Val.Text= string(B);
            %% Calculate The error
            e_squar=(A.*X+B-Y).^2;
            e=sqrt(sum(e_squar));
            app.error_val.Text= string(sqrt(sum(e_squar)));
            %% Calculate The Mean
            app.Mean_val.Text= string(1/length(X)*e)
            %%
            app.A.Visible=1;app.A_VaL.Visible=1;app.B.Visible=1;app.B_Val.Visible=1;app.error.Visible=1;app.error_val.Visible=1;app.Mean.Visible=1;app.Mean_val.Visible=1
            hold(app.plot, 'on')
            plot(app.plot,X,Y,'*')
            x=linspace(X(1),X(length(X)),200);
            plot(app.plot,x,A*x+B)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Least_Square = uifigure('Visible', 'off');
            app.Least_Square.Name = 'Least Square';
            app.Least_Square.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Least_Square);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Solve = uibutton(app.LeftPanel, 'push');
            app.Solve.ButtonPushedFcn = createCallbackFcn(app, @SolvePushed, true);
            app.Solve.Text = 'Solve';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'text');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'text');
            app.A = uilabel(app.LeftPanel);
            app.A.Visible = 'off';
            app.A.Text = 'A';
            app.A_VaL = uilabel(app.LeftPanel);
            app.A_VaL.Visible = 'off';
            app.A_VaL.Text = 'A_Val';
            app.B = uilabel(app.LeftPanel);
            app.B.Visible = 'off';
            app.B.Text = 'B';
            app.B_Val = uilabel(app.LeftPanel);
            app.B_Val.Visible = 'off';
            app.error = uilabel(app.LeftPanel);
            app.error.Visible = 'off';
            app.error.Text = 'error';
            app.error_val = uilabel(app.LeftPanel);
            app.error_val.Visible = 'off';
            app.Mean = uilabel(app.LeftPanel);
            app.Mean.Visible = 'off';
            app.Mean.Text = 'Mean';
            app.Mean_val = uilabel(app.LeftPanel);
            app.Mean_val.Visible = 'off';
            app.EnterTheDataseparatedwithacommaLabel = uilabel(app.LeftPanel);
            app.EnterTheDataseparatedwithacommaLabel.Text = 'Enter The Data separated with a comma ,';
            app.RightPanel = uipanel(app.GridLayout);
            app.plot = uiaxes(app.RightPanel);
            title(app.plot, 'Least Square')
            xlabel(app.plot, 'x')
            ylabel(app.plot, 'y')
            % Show the figure after all components are created
            app.Least_Square.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = least_Squares

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Least_Square)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Least_Square)
        end
    end
end